classdef IR_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        DevelopedbyMohammadAminAlamalhodaLabel  matlab.ui.control.Label
        ImageRegistrationUsingEnhancedCPDAlgorithmLabel  matlab.ui.control.Label
        LoadFirstImageButton   matlab.ui.control.Button
        LoadSecondImageButton  matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFirstImageButton
        function LoadFirstImageButtonPushed(app, event)
            [filename,path] = uigetfile('*.nii');
            loaddata = fullfile(path,filename);
            Img = niftiread(loaddata);
            ax = app.UIAxes;
            ptCloud = Pcloudmaker(Img);
            ptCloud = pcdownsample(ptCloud,'gridAverage',5);
            xyz = ptCloud.Location;
            markerSize = 1;
            try 
                MyPcshow(ptCloud, 'Parent', ax);
            catch
                 close Figure 1
            end
            colormap(ax,"jet")
        end

        % Button pushed function: LoadSecondImageButton
        function LoadSecondImageButtonPushed(app, event)
            [filename,path] = uigetfile('*.nii');
            loaddata = fullfile(path,filename);
            Img = niftiread(loaddata);
            ax = app.UIAxes2;
            ptCloud = Pcloudmaker(Img);
            ptCloud = pcdownsample(ptCloud,'gridAverage',5);
            xyz = ptCloud.Location;
            markerSize = 1;
            try 
                MyPcshow(ptCloud, 'Parent', ax);
            catch
                 close Figure 1
            end
            colormap(ax,"jet")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {618, 618};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DevelopedbyMohammadAminAlamalhodaLabel = uilabel(app.LeftPanel);
            app.DevelopedbyMohammadAminAlamalhodaLabel.Text = 'Developed by MohammadAmin Alamalhoda';
            app.ImageRegistrationUsingEnhancedCPDAlgorithmLabel = uilabel(app.LeftPanel);
            app.ImageRegistrationUsingEnhancedCPDAlgorithmLabel.Text = {'Image Registration '; 'Using Enhanced CPD Algorithm'};
            app.LoadFirstImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadFirstImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFirstImageButtonPushed, true);
            app.LoadFirstImageButton.Text = 'Load First Image';
            app.LoadSecondImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadSecondImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSecondImageButtonPushed, true);
            app.LoadSecondImageButton.Text = 'Load Second Image';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Image 1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Image 2')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IR_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end